classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        HomeTab             matlab.ui.container.Tab
        LaunchRobotButton   matlab.ui.control.Button
        PickandPlaceButton  matlab.ui.control.Button
        TeachButton         matlab.ui.control.Button
        Image               matlab.ui.control.Image
        TeachTab            matlab.ui.container.Tab
        q5Slider            matlab.ui.control.Slider
        q5SliderLabel       matlab.ui.control.Label
        Spinner_8           matlab.ui.control.Spinner
        EditField_2         matlab.ui.control.EditField
        NoButton_2          matlab.ui.control.Button
        YesButton_2         matlab.ui.control.Button
        RESUMEButton_2      matlab.ui.control.Button
        q4Slider            matlab.ui.control.Slider
        q4SliderLabel       matlab.ui.control.Label
        q3Slider            matlab.ui.control.Slider
        q3SliderLabel       matlab.ui.control.Label
        Spinner_7           matlab.ui.control.Spinner
        Spinner_6           matlab.ui.control.Spinner
        Spinner_5           matlab.ui.control.Spinner
        q2Slider            matlab.ui.control.Slider
        q2SliderLabel       matlab.ui.control.Label
        Spinner_4           matlab.ui.control.Spinner
        Spinner_3           matlab.ui.control.Spinner
        Spinner_2           matlab.ui.control.Spinner
        Spinner             matlab.ui.control.Spinner
        Label               matlab.ui.control.Label
        q1Slider            matlab.ui.control.Slider
        q1SliderLabel       matlab.ui.control.Label
        Button_2            matlab.ui.control.Button
        ZButton_2           matlab.ui.control.Button
        ZButton             matlab.ui.control.Button
        YButton_3           matlab.ui.control.Button
        YButton_2           matlab.ui.control.Button
        XButton_2           matlab.ui.control.Button
        XButton             matlab.ui.control.Button
        PickandPlaceTab     matlab.ui.container.Tab
        LightSwitchButton   matlab.ui.control.Button
        EditField           matlab.ui.control.EditField
        NoButton            matlab.ui.control.Button
        YesButton           matlab.ui.control.Button
        RESUMEButton        matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
    end

    
    properties (Access = private)
        Dobot;% Description
        qDobot=[0     0     0     0]; % Description
        workspace=[-1 1 -1 1 -0 1]; % Description
        Cerealbox;
        Muselibox;
        SauceBottle;
        Shelf1;
        Shelf2;
        Fire_Extinguisher;
        Barrier1;
        Barrier2;
        Barrier3;
        Barrier4;
        Barrier5;
        Barrier6;
        Barrier7;
        Shelf_Box1;
        Shelf_Box2;
        Box;
        Objarray;
        
        
        emergencyButtonPressed % Description
        Property21 % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LaunchRobotButton
        function LaunchRobotButtonPushed(app, event)
            %close all;
            %clear all;
            
            app.EditField.Visible= 'off';
            app.YesButton.Visible='off';
            app.NoButton.Visible='off';
            
            
            app.EditField_2.Visible= 'off';
            app.YesButton_2.Visible='off';
            app.NoButton_2.Visible='off';
            
            emergencyButtonPressed=0;
            
            app.Dobot = LaunchDobot([0,0,0]);

            %Load the Environment
            
            hold on;
            axis([-0.6 0.6,-0.6 0.6,0 0.7]);
            surf([-0.6,-0.6;0.6,0.6],[-0.6,0.6;-0.6,0.6],[0,0;0,0],'CData',imread('floor.jpg'),'FaceColor','texturemap');
            surf([0.6,0.6;0.6,0.6],[-0.6,0.6;-0.6,0.6],[0.7,0.7;0,0],'CData',imread('Wall.jpg'),'FaceColor','texturemap');
            surf([-0.6,0.6;-0.6,0.6],[-0.6,-0.6;-0.6,-0.6],[0.7,0.7;0,0],'CData',imread('Wall.jpg'),'FaceColor','texturemap');
            surf([0.6,0.6;0.6,0.6],[0.5,0.3;0.5,0.3],[0.4,0.4;0.25,0.25],'CData',imread('Warning_sign.jpg'),'FaceColor','texturemap');
            
            % Objects
            
            hold on;
            
            
            % Moveable
            app.Cerealbox = BasicObject('Cereal_Box.ply',[-0.1,0.3,0.0],1,[0.3,0.135,0.125],[0.2,0.125,0.13]);
            
            app.Muselibox = BasicObject('Museli_Box.ply',[-0.175,0.2,0],1,[0.1,-0.33,0.11],[0.2,0,0.12]);
            
            app.SauceBottle = BasicObject('Sauce_Can.ply',[-0.1,0.2,0],1,[0.3,-0.1,0.1],[0.2,0.15,0.13]);
            
            
            % Environment
            app.Shelf1 = BasicObject('Shelf.ply',[0.3,0,0],0,[],[]);
            
            app.Shelf2 = BasicObject('Shelf_2.ply',[0.035,-0.32,0],0,[],[]);
            
            app.Fire_Extinguisher = BasicObject('Fireextinguisher.ply',[0.3,0.25,0],0,[],[]);
            
            app.Barrier1 = BasicObject('Barrier_1.ply',[0.2,0.4,0],0,[],[]);
            
            app.Barrier2 = BasicObject('Barrier_1.ply',[-0.2,0.4,0],0,[],[]);
            
            app.Barrier3 = BasicObject('Barrier_2.ply',[-0.4,-0.2,0],0,[],[]);
            
            app.Barrier4 = BasicObject('Barrier_2.ply',[-0.4,0.2,0],0,[],[]);
            
            app.Barrier5 = BasicObject('Barrier_2.ply',[0.45,0.2,0],0,[],[]);
            
            app.Barrier6 = BasicObject('Barrier_1.ply',[-0.2,-0.5,0],0,[],[]);
            
            %app.Barrier7 = BasicObject('Barrier_1.ply',[0.,0.16,0],0,[],[]);
            
            app.Shelf_Box1 = BasicObject('Stationary_Box.ply',[0.34,0.17,0],0,[],[]);
            
            app.Shelf_Box2 = BasicObject('Stationary_Box.ply',[0.34,0.1,0],0,[],[]);
            
            app.Box = BasicObject('Box.ply',[-0.15,0.25,0],0,[],[]);


            app.Objarray = [app.Cerealbox, app.Muselibox, app.SauceBottle, app.Shelf1, app.Shelf2, app.Fire_Extinguisher, app.Barrier1, app.Barrier2, app.Barrier3, app.Barrier4, app.Barrier5, app.Barrier6, app.Shelf_Box1, app.Shelf_Box2, app.Box];
            
            %app.Objarray = [app.Cerealbox, app.Muselibox, app.SauceBottle, app.Shelf1, app.Shelf2, app.Fire_Extinguisher, app.Barrier1, app.Barrier2, app.Barrier3, app.Barrier4, app.Barrier5, app.Barrier6,app.Barrier7, app.Shelf_Box1, app.Shelf_Box2, app.Box];

        end

        % Button pushed function: TeachButton
        function TeachButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TeachTab;
            
        end

        % Button pushed function: PickandPlaceButton
        function PickandPlaceButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.PickandPlaceTab;
            PickAndPlace(app.Dobot,app.Objarray);
            
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            q = app.Dobot.model.getpos; 
            tr = app.Dobot.model.fkine(q); 
            tr(1,4) = tr(1,4) + 0.01; 
            newQ = app.Dobot.model.ikcon(tr,q); 
            app.Dobot.model.animate(newQ); 
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            q = app.Dobot.model.getpos; 
            tr = app.Dobot.model.fkine(q); 
            tr(1,4) = tr(1,4) - 0.01; 
            newQ = app.Dobot.model.ikcon(tr,q); 
            app.Dobot.model.animate(newQ); 
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            q = app.Dobot.model.getpos; 
            tr = app.Dobot.model.fkine(q); 
            tr(2,4) = tr(2,4) + 0.01; 
            newQ = app.Dobot.model.ikcon(tr,q); 
            app.Dobot.model.animate(newQ); 
        end

        % Button pushed function: YButton_3
        function YButton_3Pushed(app, event)
            q = app.Dobot.model.getpos; 
            tr = app.Dobot.model.fkine(q); 
            tr(2,4) = tr(2,4) - 0.01; 
            newQ = app.Dobot.model.ikcon(tr,q); 
            app.Dobot.model.animate(newQ); 
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            q = app.Dobot.model.getpos; 
            tr = app.Dobot.model.fkine(q); 
            tr(3,4) = tr(3,4) + 0.01; 
            newQ = app.Dobot.model.ikcon(tr,q); 
            app.Dobot.model.animate(newQ); 
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            q = app.Dobot.model.getpos; 
            tr = app.Dobot.model.fkine(q); 
            tr(3,4) = tr(3,4) - 0.01; 
            newQ = app.Dobot.model.ikcon(tr,q); 
            app.Dobot.model.animate(newQ); 
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            value = app.q1Slider.Value;
            q=app.Dobot.model.getpos;
            q(1)=deg2rad(value);
            app.Dobot.model.animate(q);
        end

        % Value changing function: q1Slider
        function q1SliderValueChanging(app, event)
            changingValue = event.Value;
            q=app.Dobot.model.getpos;
            q(1)=deg2rad(changingValue);
            app.Dobot.model.animate(q);
            
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            app.q1Slider.Value=value;
            q=app.Dobot.model.getpos;
            q(1)=deg2rad(value);
            app.Dobot.model.animate(q);
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.q1Slider.Value=changingValue;
            q=app.Dobot.model.getpos;
            q(1)=deg2rad(changingValue);
            app.Dobot.model.animate(q);
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            value = app.q2Slider.Value;
            q=app.Dobot.model.getpos;
            q(2)=deg2rad(value);
            app.Dobot.model.animate(q);
            
        end

        % Value changing function: q2Slider
        function q2SliderValueChanging(app, event)
            changingValue = event.Value;
            q=app.Dobot.model.getpos;
            q(2)=deg2rad(changingValue);
            app.Dobot.model.animate(q);
        end

        % Value changed function: Spinner_5
        function Spinner_5ValueChanged(app, event)
            value = app.Spinner_5.Value;
            app.q2Slider.Value=value;
            q=app.Dobot.model.getpos;
            q(2)=deg2rad(value);
            app.Dobot.model.animate(q);
        end

        % Value changing function: Spinner_5
        function Spinner_5ValueChanging(app, event)
            changingValue = event.Value;
            app.q2Slider.Value=changingValue;
            q=app.Dobot.model.getpos;
            q(2)=deg2rad(changingValue);
            app.Dobot.model.animate(q);
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            value = app.q3Slider.Value;
            q=app.Dobot.model.getpos;
            q(3)=deg2rad(value);
            app.Dobot.model.animate(q);
        end

        % Value changing function: q3Slider
        function q3SliderValueChanging(app, event)
            changingValue = event.Value;
            q=app.Dobot.model.getpos;
            q(3)=deg2rad(changingValue);
            app.Dobot.model.animate(q);
        end

        % Value changed function: Spinner_6
        function Spinner_6ValueChanged(app, event)
            value = app.Spinner_6.Value;
            app.q3Slider.Value=value;
            q=app.Dobot.model.getpos;
            q(3)=deg2rad(value);
            app.Dobot.model.animate(q);
        end

        % Value changing function: Spinner_6
        function Spinner_6ValueChanging(app, event)
            changingValue = event.Value;
            app.q3Slider.Value=changingValue;
            q=app.Dobot.model.getpos;
            q(3)=deg2rad(changingValue);
            app.Dobot.model.animate(q);
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            value = app.q4Slider.Value;
            q=app.Dobot.model.getpos;
            q(4)=deg2rad(value);
            app.Dobot.model.animate(q);
        end

        % Value changing function: q4Slider
        function q4SliderValueChanging(app, event)
            changingValue = event.Value;
            q=app.Dobot.model.getpos;
            q(4)=deg2rad(changingValue);
            app.Dobot.model.animate(q);
        end

        % Value changed function: Spinner_7
        function Spinner_7ValueChanged(app, event)
            value = app.Spinner_7.Value;
            app.q4Slider.Value=value;
            q=app.Dobot.model.getpos;
            q(4)=deg2rad(value);
            app.Dobot.model.animate(q);
        end

        % Value changing function: Spinner_7
        function Spinner_7ValueChanging(app, event)
            changingValue = event.Value;
            app.q4Slider.Value=changingValue;
            q=app.Dobot.model.getpos;
            q(4)=deg2rad(changingValue);
            app.Dobot.model.animate(q);
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            value = app.q5Slider.Value;
            q=app.Dobot.model.getpos;
            q(5)=deg2rad(value);
            app.Dobot.model.animate(q);
        end

        % Value changing function: q5Slider
        function q5SliderValueChanging(app, event)
            changingValue = event.Value;
            q=app.Dobot.model.getpos;
            q(5)=deg2rad(changingValue);
            app.Dobot.model.animate(q);
        end

        % Value changed function: Spinner_8
        function Spinner_8ValueChanged(app, event)
            value = app.Spinner_8.Value;
            app.q5Slider.Value=value;
            q=app.Dobot.model.getpos;
            q(5)=deg2rad(value);
            app.Dobot.model.animate(q);
        end

        % Value changing function: Spinner_8
        function Spinner_8ValueChanging(app, event)
            changingValue = event.Value;
            app.q5Slider.Value=changingValue;
            q=app.Dobot.model.getpos;
            q(5)=deg2rad(changingValue);
            app.Dobot.model.animate(q);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            app.XButton.Enable='off';
            app.XButton_2.Enable='off';
            app.YButton_2.Enable='off';
            app.YButton_3.Enable='off';
            app.ZButton.Enable='off';
            app.ZButton_2.Enable='off';
            app.q1Slider.Enable='off';
            app.q1SliderLabel.Enable='off';
            app.q2Slider.Enable='off';
            app.q2SliderLabel.Enable='off';
            app.q3Slider.Enable='off';
            app.q3SliderLabel.Enable='off';
            app.q4Slider.Enable='off';
            app.q4SliderLabel.Enable='off';
            
            app.Spinner.Enable='off';
            app.Spinner_2.Enable='off';
            app.Spinner_3.Enable='off';
            app.Spinner_4.Enable='off';
            app.Spinner_5.Enable='off';
            app.Spinner_6.Enable='off';
            app.Spinner_7.Enable='off';
            uiwait;

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            
            app.XButton.Enable='off';
            app.XButton_2.Enable='off';
            app.YButton_2.Enable='off';
            app.YButton_3.Enable='off';
            app.ZButton.Enable='off';
            app.ZButton_2.Enable='off';
            app.q1Slider.Enable='off';
            app.q1SliderLabel.Enable='off';
            app.q2Slider.Enable='off';
            app.q2SliderLabel.Enable='off';
            app.q3Slider.Enable='off';
            app.q3SliderLabel.Enable='off';
            app.q4Slider.Enable='off';
            app.q4SliderLabel.Enable='off';
            
            app.Spinner.Enable='off';
            app.Spinner_2.Enable='off';
            app.Spinner_3.Enable='off';
            app.Spinner_4.Enable='off';
            app.Spinner_5.Enable='off';
            app.Spinner_6.Enable='off';
            app.Spinner_7.Enable='off';
            uiwait;
            
        end

        % Button pushed function: RESUMEButton
        function RESUMEButtonPushed(app, event)
            app.EditField.Visible= 'on';
            app.YesButton.Visible='on';
            app.NoButton.Visible='on';
            %uiresume;
        end

        % Button pushed function: RESUMEButton_2
        function RESUMEButton_2Pushed(app, event)
            app.EditField_2.Visible= 'on';
            app.YesButton_2.Visible='on';
            app.NoButton_2.Visible='on';
            %uiresume;
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            app.EditField.Visible= 'off';
            app.YesButton.Visible='off';
            app.NoButton.Visible='off';
            
            app.XButton.Enable='on';
            app.XButton_2.Enable='on';
            app.YButton_2.Enable='on';
            app.YButton_3.Enable='on';
            app.ZButton.Enable='on';
            app.ZButton_2.Enable='on';
            app.q1Slider.Enable='on';
            app.q1SliderLabel.Enable='on';
            app.q2Slider.Enable='on';
            app.q2SliderLabel.Enable='on';
            app.q3Slider.Enable='on';
            app.q3SliderLabel.Enable='on';
            app.q4Slider.Enable='on';
            app.q4SliderLabel.Enable='on';
            
            app.Spinner.Enable='on';
            app.Spinner_2.Enable='on';
            app.Spinner_3.Enable='on';
            app.Spinner_4.Enable='on';
            app.Spinner_5.Enable='on';
            app.Spinner_6.Enable='on';
            app.Spinner_7.Enable='on';
            
            
            uiresume;
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.EditField.Visible= 'off';
            app.YesButton.Visible='off';
            app.NoButton.Visible='off';
            
        end

        % Button pushed function: YesButton_2
        function YesButton_2Pushed(app, event)
            app.EditField_2.Visible= 'off';
            app.YesButton_2.Visible='off';
            app.NoButton_2.Visible='off';
            
            app.XButton.Enable='on';
            app.XButton_2.Enable='on';
            app.YButton_2.Enable='on';
            app.YButton_3.Enable='on';
            app.ZButton.Enable='on';
            app.ZButton_2.Enable='on';
            app.q1Slider.Enable='on';
            app.q1SliderLabel.Enable='on';
            app.q2Slider.Enable='on';
            app.q2SliderLabel.Enable='on';
            app.q3Slider.Enable='on';
            app.q3SliderLabel.Enable='on';
            app.q4Slider.Enable='on';
            app.q4SliderLabel.Enable='on';
            
            app.Spinner.Enable='on';
            app.Spinner_2.Enable='on';
            app.Spinner_3.Enable='on';
            app.Spinner_4.Enable='on';
            app.Spinner_5.Enable='on';
            app.Spinner_6.Enable='on';
            app.Spinner_7.Enable='on';
           
            
            uiresume;
        end

        % Button pushed function: NoButton_2
        function NoButton_2Pushed(app, event)
            app.EditField_2.Visible= 'off';
            app.YesButton_2.Visible='off';
            app.NoButton_2.Visible='off';
        end

        % Button pushed function: LightSwitchButton
        function LightSwitchButtonPushed(app, event)
            
            app.XButton.Enable='off';
            app.XButton_2.Enable='off';
            app.YButton_2.Enable='off';
            app.YButton_3.Enable='off';
            app.ZButton.Enable='off';
            app.ZButton_2.Enable='off';
            app.q1Slider.Enable='off';
            app.q1SliderLabel.Enable='off';
            app.q2Slider.Enable='off';
            app.q2SliderLabel.Enable='off';
            app.q3Slider.Enable='off';
            app.q3SliderLabel.Enable='off';
            app.q4Slider.Enable='off';
            app.q4SliderLabel.Enable='off';
            app.Spinner.Enable='off';
            app.Spinner_2.Enable='off';
            app.Spinner_3.Enable='off';
            app.Spinner_4.Enable='off';
            app.Spinner_5.Enable='off';
            app.Spinner_6.Enable='off';
            app.Spinner_7.Enable='off';
            uiwait;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = 'Robot.jpg';
            app.TeachButton = uibutton(app.HomeTab, 'push');
            app.TeachButton.ButtonPushedFcn = createCallbackFcn(app, @TeachButtonPushed, true);
            app.TeachButton.Text = 'Teach';
            app.PickandPlaceButton = uibutton(app.HomeTab, 'push');
            app.PickandPlaceButton.ButtonPushedFcn = createCallbackFcn(app, @PickandPlaceButtonPushed, true);
            app.PickandPlaceButton.Text = 'Pick and Place';
            app.LaunchRobotButton = uibutton(app.HomeTab, 'push');
            app.LaunchRobotButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchRobotButtonPushed, true);
            app.LaunchRobotButton.Text = 'Launch Robot';
            app.TeachTab = uitab(app.TabGroup);
            app.TeachTab.Title = 'Teach';
            app.XButton = uibutton(app.TeachTab, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X+';
            app.XButton_2 = uibutton(app.TeachTab, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = 'X-';
            app.YButton_2 = uibutton(app.TeachTab, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = 'Y+';
            app.YButton_3 = uibutton(app.TeachTab, 'push');
            app.YButton_3.ButtonPushedFcn = createCallbackFcn(app, @YButton_3Pushed, true);
            app.YButton_3.Text = 'Y-';
            app.ZButton = uibutton(app.TeachTab, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Z+';
            app.ZButton_2 = uibutton(app.TeachTab, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = 'Z-';
            app.Button_2 = uibutton(app.TeachTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'Emergency Stop.jpg';
            app.Button_2.Text = '';
            app.q1SliderLabel = uilabel(app.TeachTab);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.TeachTab);
            app.q1Slider.Limits = [-180 180];
            app.q1Slider.MajorTicks = [-180 -90 0 90 180];
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @q1SliderValueChanging, true);
            app.q1Slider.MinorTicks = [-135 -45 45 135];
            app.Label = uilabel(app.TeachTab);
            app.Label.Text = ' ';
            app.Spinner = uispinner(app.TeachTab);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.Limits = [-180 180];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner_2 = uispinner(app.TeachTab);
            app.Spinner_2.Visible = 'off';
            app.Spinner_3 = uispinner(app.TeachTab);
            app.Spinner_3.Visible = 'off';
            app.Spinner_4 = uispinner(app.TeachTab);
            app.Spinner_4.Visible = 'off';
            app.q2SliderLabel = uilabel(app.TeachTab);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.TeachTab);
            app.q2Slider.Limits = [-180 180];
            app.q2Slider.MajorTicks = [-180 -90 0 90 180];
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @q2SliderValueChanging, true);
            app.q2Slider.MinorTicks = [-135 -45 45 135];
            app.Spinner_5 = uispinner(app.TeachTab);
            app.Spinner_5.ValueChangingFcn = createCallbackFcn(app, @Spinner_5ValueChanging, true);
            app.Spinner_5.Limits = [-180 180];
            app.Spinner_5.ValueChangedFcn = createCallbackFcn(app, @Spinner_5ValueChanged, true);
            app.Spinner_6 = uispinner(app.TeachTab);
            app.Spinner_6.ValueChangingFcn = createCallbackFcn(app, @Spinner_6ValueChanging, true);
            app.Spinner_6.Limits = [-180 180];
            app.Spinner_6.ValueChangedFcn = createCallbackFcn(app, @Spinner_6ValueChanged, true);
            app.Spinner_7 = uispinner(app.TeachTab);
            app.Spinner_7.ValueChangingFcn = createCallbackFcn(app, @Spinner_7ValueChanging, true);
            app.Spinner_7.Limits = [-180 180];
            app.Spinner_7.ValueChangedFcn = createCallbackFcn(app, @Spinner_7ValueChanged, true);
            app.q3SliderLabel = uilabel(app.TeachTab);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.TeachTab);
            app.q3Slider.Limits = [-180 180];
            app.q3Slider.MajorTicks = [-180 -90 0 90 180];
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @q3SliderValueChanging, true);
            app.q3Slider.MinorTicks = [-135 -45 45 135];
            app.q4SliderLabel = uilabel(app.TeachTab);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.TeachTab);
            app.q4Slider.Limits = [-180 180];
            app.q4Slider.MajorTicks = [-180 -90 0 90 180];
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @q4SliderValueChanging, true);
            app.q4Slider.MinorTicks = [-135 -45 45 135];
            app.RESUMEButton_2 = uibutton(app.TeachTab, 'push');
            app.RESUMEButton_2.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButton_2Pushed, true);
            app.RESUMEButton_2.Text = 'RESUME';
            app.YesButton_2 = uibutton(app.TeachTab, 'push');
            app.YesButton_2.ButtonPushedFcn = createCallbackFcn(app, @YesButton_2Pushed, true);
            app.YesButton_2.Text = 'Yes';
            app.NoButton_2 = uibutton(app.TeachTab, 'push');
            app.NoButton_2.ButtonPushedFcn = createCallbackFcn(app, @NoButton_2Pushed, true);
            app.NoButton_2.Text = 'No';
            app.EditField_2 = uieditfield(app.TeachTab, 'text');
            app.EditField_2.Value = '  Are you sure?';
            app.Spinner_8 = uispinner(app.TeachTab);
            app.Spinner_8.ValueChangingFcn = createCallbackFcn(app, @Spinner_8ValueChanging, true);
            app.Spinner_8.Limits = [-180 180];
            app.Spinner_8.ValueChangedFcn = createCallbackFcn(app, @Spinner_8ValueChanged, true);
            app.q5SliderLabel = uilabel(app.TeachTab);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.TeachTab);
            app.q5Slider.Limits = [-180 180];
            app.q5Slider.MajorTicks = [-180 -90 0 90 180];
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @q5SliderValueChanging, true);
            app.q5Slider.MinorTicks = [-135 -45 45 135];
            app.PickandPlaceTab = uitab(app.TabGroup);
            app.PickandPlaceTab.Title = 'Pick and Place';
            app.Button_3 = uibutton(app.PickandPlaceTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'Emergency Stop.jpg';
            app.Button_3.Text = '';
            app.RESUMEButton = uibutton(app.PickandPlaceTab, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButtonPushed, true);
            app.RESUMEButton.Text = 'RESUME';
            app.YesButton = uibutton(app.PickandPlaceTab, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.PickandPlaceTab, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.EditField = uieditfield(app.PickandPlaceTab, 'text');
            app.EditField.Value = '  Are you sure?';
            app.LightSwitchButton = uibutton(app.PickandPlaceTab, 'push');
            app.LightSwitchButton.ButtonPushedFcn = createCallbackFcn(app, @LightSwitchButtonPushed, true);
            app.LightSwitchButton.Text = 'Light  Switch';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end